classdef ex34_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CALCULATORPanel           matlab.ui.container.Panel
        ResulthexEditField        matlab.ui.control.EditField
        ResulthexEditFieldLabel   matlab.ui.control.Label
        ResultdecEditField        matlab.ui.control.NumericEditField
        ResultdecEditFieldLabel   matlab.ui.control.Label
        HexCheckBox               matlab.ui.control.CheckBox
        ExpressionEditField       matlab.ui.control.EditField
        ExpressionEditFieldLabel  matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            DecVal = eval(app.ExpressionEditField.Value);
            if app.HexCheckBox.Value == true
                app.ResulthexEditField.Value = dec2hex(DecVal);
            else
                app.ResulthexEditField.Value = '';
            end
            app.ResultdecEditField.Value = DecVal;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CALCULATORPanel = uipanel(app.UIFigure);
            app.CALCULATORPanel.Title = 'CALCULATOR';
            app.RunButton = uibutton(app.CALCULATORPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExpressionEditFieldLabel = uilabel(app.CALCULATORPanel);
            app.ExpressionEditFieldLabel.Text = 'Expression';
            app.ExpressionEditField = uieditfield(app.CALCULATORPanel, 'text');
            app.HexCheckBox = uicheckbox(app.CALCULATORPanel);
            app.HexCheckBox.Text = 'Hex';
            app.ResultdecEditFieldLabel = uilabel(app.CALCULATORPanel);
            app.ResultdecEditFieldLabel.Text = 'Result (dec)';
            app.ResultdecEditField = uieditfield(app.CALCULATORPanel, 'numeric');
            app.ResulthexEditFieldLabel = uilabel(app.CALCULATORPanel);
            app.ResulthexEditFieldLabel.Text = 'Result (hex)';
            app.ResulthexEditField = uieditfield(app.CALCULATORPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex34_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end